classdef ParameterSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultsTable                matlab.ui.control.Table
        CancelButton                matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        StatusLabel                 matlab.ui.control.Label
        RankByDropDown              matlab.ui.control.DropDown
        RankByDropDownLabel         matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        MaxCombosEditField          matlab.ui.control.NumericEditField
        MaxCombosEditFieldLabel     matlab.ui.control.Label
        ComboCountLabel             matlab.ui.control.Label
        SweepTable                  matlab.ui.control.Table
        BuildRowsButton             matlab.ui.control.Button
        SkeletonMethodListBox       matlab.ui.control.ListBox
        SkeletonMethodListBoxLabel  matlab.ui.control.Label
        EnhanceMethodListBox        matlab.ui.control.ListBox
        EnhanceMethodListBoxLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        OutputParams = [] % Description
    end

    properties (Access = private)
        Im
        Gt
        RoiMask
        Cisterna
        ErMask
        ErFenestrations
        CellBoundary
        ImBackground

        EnhanceCurrentParams
        EnhanceDefaults
        SkeletonCurrentParams
        SkeletonDefaults

        Tolerance
        FwhmTarget
        Code

        Rows                        % struct array: step/method/parameter/current/tune/min/inc/max
        RowsEnhanceMethods  = {}    % candidate method lists snapshotted at last "Build sweep table"
        RowsSkeletonMethods = {}

        Combos              % last-built combo list (parameterSelectorExpandCombos output)
        Results             % last-run results table (parameterSelectorRunSweep output
    end

    methods (Access = private)

        function rows = buildRowsForStep(app, step, methods, defaults, currentParams)
            rows = struct('step', {}, 'method', {}, 'parameter', {}, 'current', {}, ...
                'tune', {}, 'min', {}, 'inc', {}, 'max', {});
            for mi = 1:numel(methods)
                m = methods{mi};
                f = parameterSelectorMethodFields(defaults, currentParams, m);
                for k = 1:numel(f)
                    r = struct('step', step, 'method', m, 'parameter', f(k).name, ...
                        'current', f(k).current, 'tune', false, ...
                        'min', f(k).current, 'inc', 1, 'max', f(k).current);
                    rows(end + 1) = r; %#ok<AGROW>
                end
            end
        end

        function refreshSweepTable(app)
            n = numel(app.Rows);
            colNames = {'Step', 'Method', 'Parameter', 'Current', 'Tune', 'Min', 'Inc', 'Max'};
            if n == 0
                app.SweepTable.Data = cell2table(cell(0, 8), 'VariableNames', colNames);
                app.SweepTable.ColumnName = colNames;
                return
            end
            data = cell(n, 8);
            for i = 1:n
                r = app.Rows(i);
                data(i, :) = {r.step, r.method, r.parameter, r.current, r.tune, r.min, r.inc, r.max};
            end
            T = cell2table(data, 'VariableNames', colNames);
            app.SweepTable.Data = T;
            app.SweepTable.ColumnName = colNames;   % table Data does NOT reliably auto-populate headers
            app.SweepTable.ColumnFormat = {'char', 'char', 'char', 'numeric', 'logical', 'numeric', 'numeric', 'numeric'};
            app.SweepTable.ColumnEditable = [false false false true true true true true];
        end

        function updateComboCount(app)
            if isempty(app.Rows) || isempty(app.RowsEnhanceMethods) || isempty(app.RowsSkeletonMethods)
                app.ComboCountLabel.Text = 'Combinations: 0';
                app.ComboCountLabel.FontColor = [0 0 0];
                app.RunButton.Enable = 'off';
                return
            end
            try
                combos = parameterSelectorExpandCombos(app.Rows, app.RowsEnhanceMethods, app.RowsSkeletonMethods);
                n = numel(combos);
                app.ComboCountLabel.Text = sprintf('Combinations: %d', n);
                if n > app.MaxCombosEditField.Value
                    app.ComboCountLabel.FontColor = [0.8 0 0];
                    app.RunButton.Enable = 'off';
                else
                    app.ComboCountLabel.FontColor = [0 0 0];
                    app.RunButton.Enable = 'on';
                end
            catch ME
                app.ComboCountLabel.Text = ['Combinations: invalid range -- ' ME.message];
                app.ComboCountLabel.FontColor = [0.8 0 0];
                app.RunButton.Enable = 'off';
            end
        end

        function refreshResultsTable(app)
            if isempty(app.Results)
                app.ResultsTable.Data = [];
                app.ResultsTable.ColumnName = {};
                return
            end

            % Surface the actual swept parameter values per combo -- otherwise two
            % combos that share a method name look identical at a glance even when
            % their tuned fields genuinely differ, which is impossible to diagnose
            % from method name + score columns alone.
            n = height(app.Results);
            enhanceParamsStr = strings(n, 1);
            skeletonParamsStr = strings(n, 1);
            for i = 1:n
                c = app.Combos(app.Results.combo(i));
                enhanceParamsStr(i) = app.formatComboValues(c.enhanceValues);
                skeletonParamsStr(i) = app.formatComboValues(c.skeletonValues);
            end
            T = app.Results;
            T.enhanceParams = enhanceParamsStr;
            T.skeletonParams = skeletonParamsStr;

            metric = app.RankByDropDown.Value;
            T = sortrows(T, metric, 'descend');

            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;
        end

        function str = formatComboValues(app, s) %#ok<INUSD>
            f = fieldnames(s);
            if isempty(f)
                str = "(none)";
                return
            end
            parts = cell(numel(f), 1);
            for i = 1:numel(f)
                parts{i} = sprintf('%s=%.4g', f{i}, s.(f{i}));
            end
            str = string(strjoin(parts, ', '));
        end

        function reportProgress(app, d, k, n)
            d.Value = k / n;
            d.Message = sprintf('Combo %d / %d', k, n);
            if d.CancelRequested
                error('ParameterSelector:sweepCancelled', 'Sweep cancelled by user.');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, gt, roiMask, cisterna, erMask, erFenestrations, cellBoundary, imBackground, enhanceCurrentParams, enhanceDefaults, skeletonCurrentParams, skeletonDefaults, tolerance, fwhmTarget, code)
            % Put this app's own src/ folder on path (it sits next to the .mlapp).
            appRoot = fileparts(which('ParameterSelector'));
            if ~isempty(appRoot) && exist(fullfile(appRoot, 'src'), 'dir')
                addpath(fullfile(appRoot, 'src'));
            end

            app.Im               = im;
            app.Gt               = gt;
            app.RoiMask          = roiMask;
            app.Cisterna         = cisterna;
            app.ErMask           = erMask;
            app.ErFenestrations  = erFenestrations;
            app.CellBoundary     = cellBoundary;
            app.ImBackground     = imBackground;

            app.EnhanceCurrentParams  = enhanceCurrentParams;
            app.EnhanceDefaults       = enhanceDefaults;
            app.SkeletonCurrentParams = skeletonCurrentParams;
            app.SkeletonDefaults      = skeletonDefaults;

            app.Tolerance  = tolerance;
            app.FwhmTarget = fwhmTarget;
            app.Code       = code;
            app.OutputParams = [];

            % funcGenericMethodsFromDefaults (AnalyzERproject_sandbox/core/utils) is
            % already on path -- this app is only ever launched from a running
            % AnalyzER session, never standalone.
            enhanceItems = funcGenericMethodsFromDefaults(enhanceDefaults);
            enhanceItems(strcmp(enhanceItems, 'none')) = [];
            app.EnhanceMethodListBox.Items = enhanceItems;

            skeletonItems = funcGenericMethodsFromDefaults(skeletonDefaults, {}, {'ridgeWatershed', 'WS + NMS'});
            skeletonItems(strcmp(skeletonItems, 'none')) = [];
            app.SkeletonMethodListBox.Items = skeletonItems;

            if isfield(enhanceCurrentParams, 'method') && any(strcmp(enhanceItems, enhanceCurrentParams.method))
                app.EnhanceMethodListBox.Value = {enhanceCurrentParams.method};
            end
            if isfield(skeletonCurrentParams, 'method') && any(strcmp(skeletonItems, skeletonCurrentParams.method))
                app.SkeletonMethodListBox.Value = {skeletonCurrentParams.method};
            end

            app.RankByDropDown.Items = {'F1', 'dPrime', 'MCC', 'Fbeta2', 'precision', 'recall'};
            app.RankByDropDown.Value = 'F1';
            app.MaxCombosEditField.Value = 200;
            sweepColNames = {'Step', 'Method', 'Parameter', 'Current', 'Tune', 'Min', 'Inc', 'Max'};
            app.SweepTable.ColumnName = sweepColNames;
            app.SweepTable.ColumnFormat = {'char', 'char', 'char', 'numeric', 'logical', 'numeric', 'numeric', 'numeric'};
            app.SweepTable.Data = cell2table(cell(0, 8), 'VariableNames', sweepColNames);
            app.ResultsTable.ColumnName = {};
            app.ComboCountLabel.Text = 'Combinations: 0';
            app.RunButton.Enable = 'off';
            app.StatusLabel.Text = '';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Do NOT delete(app) here -- the caller still needs to read
            % OutputParams after uiwait(app.UIFigure) returns. Just hide and
            % resume; the caller deletes the app once it has read OutputParams
            % (mirrors FungalNetworkAnalysis.m's confirm-then-apply pattern rather
            % than the legacy tool's silent overwrite-on-OK).
            app.OutputParams = [];
            uiresume(app.UIFigure);
        end

        % Button pushed function: BuildRowsButton
        function BuildRowsButtonPushed(app, event)
            enhanceMethods  = app.EnhanceMethodListBox.Value;
            skeletonMethods = app.SkeletonMethodListBox.Value;
            if isempty(enhanceMethods) || isempty(skeletonMethods)
                uialert(app.UIFigure, ...
                    'Select at least one candidate method for both Enhance and Skeleton.', ...
                    'Missing methods');
                return
            end

            rows = struct('step', {}, 'method', {}, 'parameter', {}, 'current', {}, ...
                'tune', {}, 'min', {}, 'inc', {}, 'max', {});
            rows = [rows, app.buildRowsForStep('enhance',  enhanceMethods,  app.EnhanceDefaults,  app.EnhanceCurrentParams)];
            rows = [rows, app.buildRowsForStep('skeleton', skeletonMethods, app.SkeletonDefaults, app.SkeletonCurrentParams)];

            app.Rows = rows;
            app.RowsEnhanceMethods  = enhanceMethods;
            app.RowsSkeletonMethods = skeletonMethods;

            app.refreshSweepTable();
            app.updateComboCount();
            app.StatusLabel.Text = '';   
        end

        % Cell edit callback: SweepTable
        function SweepTableCellEdit(app, event)
            fieldNames = {'step', 'method', 'parameter', 'current', 'tune', 'min', 'inc', 'max'};
            r = event.Indices(1);
            c = event.Indices(2);
            app.Rows(r).(fieldNames{c}) = event.NewData;
            app.updateComboCount();
        end

        % Value changed function: MaxCombosEditField
        function MaxCombosEditFieldValueChanged(app, event)
            app.updateComboCount();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                combos = parameterSelectorExpandCombos(app.Rows, app.RowsEnhanceMethods, app.RowsSkeletonMethods);
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid sweep range');
                return
            end
            if numel(combos) > app.MaxCombosEditField.Value
                uialert(app.UIFigure, sprintf( ...
                    '%d combinations exceeds the limit of %d. Narrow the sweep or raise the limit.', ...
                    numel(combos), app.MaxCombosEditField.Value), 'Too many combinations');
                return
            end
            app.Combos = combos;

            d = uiprogressdlg(app.UIFigure, 'Title', 'Running sweep', ...
                'Message', 'Starting...', 'Cancelable', 'on');
            cleanupObj = onCleanup(@() close(d)); %#ok<NASGU>
            progressFcn = @(k, n) app.reportProgress(d, k, n);

            try
                results = parameterSelectorRunSweep(app.Im, app.Gt, app.RoiMask, app.Cisterna, ...
                    app.ErMask, app.ErFenestrations, app.CellBoundary, app.ImBackground, ...
                    app.EnhanceCurrentParams, app.SkeletonCurrentParams, combos, ...
                    app.Tolerance, app.FwhmTarget, app.Code, progressFcn);
            catch ME
                if strcmp(ME.identifier, 'ParameterSelector:sweepCancelled')
                    app.StatusLabel.Text = 'Sweep cancelled.';
                else
                    uialert(app.UIFigure, ME.message, 'Sweep failed');
                end
                return
            end

            app.Results = results;
            app.refreshResultsTable();
            app.StatusLabel.Text = sprintf('Sweep complete: %d combinations.', height(results));   
        end

        % Value changed function: RankByDropDown
        function RankByDropDownValueChanged(app, event)
            app.refreshResultsTable();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            sel = app.ResultsTable.Selection;
            if isempty(sel)
                uialert(app.UIFigure, 'Select a row in the results table first.', 'No selection');
                return
            end
            row = sel(1);
            comboIdx = app.ResultsTable.Data.combo(row);
            winner = app.Combos(comboIdx);

            enhanceOut = parameterSelectorApplyToParams( ...
                app.EnhanceDefaults, app.EnhanceCurrentParams, winner.enhanceMethod, winner.enhanceValues);
            skeletonOut = parameterSelectorApplyToParams( ...
                app.SkeletonDefaults, app.SkeletonCurrentParams, winner.skeletonMethod, winner.skeletonValues);

            app.OutputParams = struct('enhance', enhanceOut, 'skeleton', skeletonOut);
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.OutputParams = [];
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnhanceMethodListBoxLabel = uilabel(app.UIFigure);
            app.EnhanceMethodListBoxLabel.Text = 'Enhance candidate methods';
            app.EnhanceMethodListBox = uilistbox(app.UIFigure);
            app.EnhanceMethodListBox.Multiselect = 'on';
            app.EnhanceMethodListBox.Value = {'Item 1'};
            app.SkeletonMethodListBoxLabel = uilabel(app.UIFigure);
            app.SkeletonMethodListBoxLabel.Text = 'Skeleton candidate methods';
            app.SkeletonMethodListBox = uilistbox(app.UIFigure);
            app.SkeletonMethodListBox.Multiselect = 'on';
            app.SkeletonMethodListBox.Value = {'Item 1'};
            app.BuildRowsButton = uibutton(app.UIFigure, 'push');
            app.BuildRowsButton.ButtonPushedFcn = createCallbackFcn(app, @BuildRowsButtonPushed, true);
            app.BuildRowsButton.Text = 'Build sweep table';
            app.SweepTable = uitable(app.UIFigure);
            app.SweepTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SweepTable.RowName = {};
            app.SweepTable.CellEditCallback = createCallbackFcn(app, @SweepTableCellEdit, true);
            app.ComboCountLabel = uilabel(app.UIFigure);
            app.ComboCountLabel.Text = 'Combinations: 0';
            app.MaxCombosEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCombosEditFieldLabel.Text = 'Max combos';
            app.MaxCombosEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCombosEditField.Limits = [1 Inf];
            app.MaxCombosEditField.ValueDisplayFormat = '%.0f';
            app.MaxCombosEditField.ValueChangedFcn = createCallbackFcn(app, @MaxCombosEditFieldValueChanged, true);
            app.MaxCombosEditField.Value = 200;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Sweep';
            app.RankByDropDownLabel = uilabel(app.UIFigure);
            app.RankByDropDownLabel.Text = 'Rank by:';
            app.RankByDropDown = uidropdown(app.UIFigure);
            app.RankByDropDown.ValueChangedFcn = createCallbackFcn(app, @RankByDropDownValueChanged, true);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply selected';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterSelector(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end